classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhotoEditorUIFigure      matlab.ui.Figure
        ShowOriginalSwitch       matlab.ui.control.Switch
        ShowOriginalSwitchLabel  matlab.ui.control.Label
        ExportButton             matlab.ui.control.Button
        validateButton           matlab.ui.control.Button
        Slider_8                 matlab.ui.control.Slider
        Slider_7                 matlab.ui.control.Slider
        HighTripletLabel         matlab.ui.control.Label
        LowTripletLabel          matlab.ui.control.Label
        AdjustcontrastLabel      matlab.ui.control.Label
        Slider_6                 matlab.ui.control.Slider
        Slider_3                 matlab.ui.control.Slider
        Slider_2                 matlab.ui.control.Slider
        Slider                   matlab.ui.control.Slider
        NoiseSlider              matlab.ui.control.Slider
        NoiseSliderLabel         matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
        imageEditField           matlab.ui.control.EditField
        imageEditFieldLabel      matlab.ui.control.Label
        PhotoEditorLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       Image
       Modified
       TempEffect
       Noise
       Exit 
    % Description
       LowR
       LowG
       LowB
       HighR
       HighG
       HighB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Supprimer le titre, les étiquettes d'axe et les étiquettes de graduation
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            %paramètres initialisés
            app.Exit = 0;
            app.LowR = 0.2;
            app.LowG = 0.3;
            app.LowB = 0;
            app.HighR = 0.6;
            app.HighG = 0.6;
            app.HighB = 1;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.gif';'*.tif';'*.*'},'File Selector');
            app.imageEditField.Value = strcat(pathname,filename);
            % Importation de l'image sélectionnée
            app.Image = imread(app.imageEditField.Value); 
            % set initial modified image 
            app.Modified = app.Image;
            % définir l'image modifiée initiale
            I = imshow(app.Modified, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.PhotoEditorUIFigure.Visible = 'off';
            app.PhotoEditorUIFigure.Visible = 'on';
                
                
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            app.Noise = app.NoiseSlider.Value;
            app.TempEffect = app.Modified;
            if app.Noise > 0 %0 is not accepted 
                app.TempEffect = imnoise(app.Modified,'gaussian',app.Noise);
                % Afficher l'image et l'étirer pour remplir les axes
                I = imshow(app.TempEffect, 'Parent',app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            else
                app.TempEffect = app.Modified;
                % Afficher l'image et l'étirer pour remplir les axes
                 I = imshow(app.TempEffect, 'Parent',app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            end
            app.Exit = 1;
            
        end

        % Value changed function: ShowOriginalSwitch
        function ShowOriginalSwitchValueChanged(app, event)
           if app.Exit == 1
               app.Modified = app.TempEffect;
               app.Exit =0; % reset
           end
           value = app.ShowOriginalSwitch.Value;
           if strcmp(value, 'On') == 1
               I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
           else
               imshow(app.Modified, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
           end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.LowR = app.Slider.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.LowG = app.Slider_2.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.LowB = app.Slider_6.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            app.HighR = app.Slider_7.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];  
        end

        % Value changed function: Slider_8
        function Slider_8ValueChanged(app, event)
            app.HighG = app.Slider_8.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];  
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.HighB = app.Slider_3.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.Image, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                %définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];   
        end

        % Button pushed function: validateButton
        function validateButtonPushed(app, event)
          app.Modified = app.TempEffect;
          I = imshow(app.Modified, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % définir les limites des axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.bmp';'*.gif';'*.tif';'*.*'};
            [file, path] = uiputfile(filter);
            if file~=0
                fig = figure;
                fig.Visible = 'off';
                figAxes = axes(fig);
                allChildren = app.UIAxes.XLim;
                copyobj(allChildren, figAxes)
                figAxes.XLim = app.UIAxes.XLim;
                figAxes.YLim = app.UIAxes.YLim;
                figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
                % Supprimer les étiquettes de coche
                figAxes.XAxis.TickLabels = {};
                figAxes.YAxis.TickLabels = {};
                % Supprimer l'axe
                set(gca, 'Visible', 'off');
                % enregistrer en tant que fichier .xyz
                saveas(fig, strcat(path,file));
                % Supprimer la figure temporaire
                delete(fig);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhotoEditorUIFigure = uifigure('Visible', 'off');
            app.PhotoEditorUIFigure.Name = 'Photo Editor';
            app.PhotoEditorUIFigure.Icon = 'Photo Editor.png';
            app.UIAxes = uiaxes(app.PhotoEditorUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhotoEditorLabel = uilabel(app.PhotoEditorUIFigure);
            app.PhotoEditorLabel.Text = 'Photo Editor';
            app.imageEditFieldLabel = uilabel(app.PhotoEditorUIFigure);
            app.imageEditFieldLabel.Text = 'image';
            app.imageEditField = uieditfield(app.PhotoEditorUIFigure, 'text');
            app.BrowseButton = uibutton(app.PhotoEditorUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'loupe.png';
            app.BrowseButton.Text = 'Browse';
            app.NoiseSliderLabel = uilabel(app.PhotoEditorUIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.PhotoEditorUIFigure);
            app.NoiseSlider.Limits = [0 1];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.NoiseSlider.MinorTicks = 1;
            app.Slider = uislider(app.PhotoEditorUIFigure);
            app.Slider.Limits = [0 0.5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.3;
            app.Slider_2 = uislider(app.PhotoEditorUIFigure);
            app.Slider_2.Limits = [0 0.5];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 0.1;
            app.Slider_3 = uislider(app.PhotoEditorUIFigure);
            app.Slider_3.Limits = [0.5 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 1;
            app.Slider_6 = uislider(app.PhotoEditorUIFigure);
            app.Slider_6.Limits = [0 0.5];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 0.4;
            app.AdjustcontrastLabel = uilabel(app.PhotoEditorUIFigure);
            app.AdjustcontrastLabel.Text = 'Adjust contrast';
            app.LowTripletLabel = uilabel(app.PhotoEditorUIFigure);
            app.LowTripletLabel.Text = 'Low Triplet';
            app.HighTripletLabel = uilabel(app.PhotoEditorUIFigure);
            app.HighTripletLabel.Text = 'High Triplet';
            app.Slider_7 = uislider(app.PhotoEditorUIFigure);
            app.Slider_7.Limits = [0.5 1];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.Value = 0.6;
            app.Slider_8 = uislider(app.PhotoEditorUIFigure);
            app.Slider_8.Limits = [0.5 1];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_8ValueChanged, true);
            app.Slider_8.Value = 0.8;
            app.validateButton = uibutton(app.PhotoEditorUIFigure, 'push');
            app.validateButton.ButtonPushedFcn = createCallbackFcn(app, @validateButtonPushed, true);
            app.validateButton.Icon = 'accept.png';
            app.validateButton.Text = 'validate';
            app.ExportButton = uibutton(app.PhotoEditorUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = 'export.png';
            app.ExportButton.Text = 'Export';
            app.ShowOriginalSwitchLabel = uilabel(app.PhotoEditorUIFigure);
            app.ShowOriginalSwitchLabel.Text = 'Show Original';
            app.ShowOriginalSwitch = uiswitch(app.PhotoEditorUIFigure, 'slider');
            app.ShowOriginalSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowOriginalSwitchValueChanged, true);
            % Show the figure after all components are created
            app.PhotoEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhotoEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhotoEditorUIFigure)
        end
    end
end